classdef GeneralGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RootSolverUIFigure       matlab.ui.Figure
        SOLVEButton              matlab.ui.control.Button
        AlgorithmsDropDownLabel  matlab.ui.control.Label
        AlgorithmsDropDown       matlab.ui.control.DropDown
        EditField                matlab.ui.control.EditField
        Label                    matlab.ui.control.Label
        SingleStepModeButton     matlab.ui.control.Button
        FastModeButton           matlab.ui.control.Button
        PleasechoosemodeLabel    matlab.ui.control.Label
        ReadFromFileButton       matlab.ui.control.Button
    end


    methods (Access = private)
    
         function err_msg = validate_eq(app, eq_as_str)
            if (eq_as_str == "") 
                err_msg = 'Please enter equation to be solved';
            else
                try 
                    f = evalin(symengine, eq_as_str);
                    symbols = symvar(f);
                    if (length(symbols) > 1)
                        err_msg = 'Invalid equation: must have only one variable, founded variables: ';
                        err_msg = strcat(err_msg,char(symbols));
                    elseif (isempty(symbols))
                        err_msg = 'Equation has no variables!';
                    else
                        err_msg = "";
                    end
                catch 
                    err_msg = 'ERROR: Invalid equation.';
                end
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: FastModeButton
        function FastModeButtonPushed(app, event)
            msg = validate_eq(app, app.EditField.Value);
            if (msg ~= "")
                app.Label.Text = msg;
            else
                algorithm_choosen = app.AlgorithmsDropDown.Value;
                switch algorithm_choosen
                    case 'Bisection'
                       bi = Bisection;
                       bi.solve(app.EditField.Value, 'fast');
                    case 'False-position'
                       fp = False_Position;
                       fp.solve(app.EditField.Value, 'fast');
                    case 'Fixed point'
                       fp = Fixed_Point;
                       fp.solve(app.EditField.Value, 'fast');
                    case 'Newton-Raphson'
                       fp = app1;
                       fp.solve(app.EditField.Value, 'fast');
                    case 'Secant'
                       secantMethod(0,app.EditField.Value,0,0,0);
                    case 'Bierge Vieta'
                       birgeVieta(0,app.EditField.Value,0,0,0,'fast');    
                    case 'General Algorithm'
                         generalMethod(0,app.EditField.Value,0,0,0,'fast');
                    otherwise
                       error('Algorithm not supported'); 
                end
            end
                
        end

        % Button pushed function: ReadFromFileButton
        function ReadFromFileBtnPushed(app, event)
            try 
                [file,filename]=uigetfile('*.txt');
                [methodNum,func,interval,tolerance,maxIter] = ReadFromFile(strcat(filename,file));
                switch methodNum   
                    case 4
                        fp = app1;
                        fp.readFile(func,interval,tolerance,maxIter);
                    case 5
                        secantMethod(1,func,interval,tolerance,maxIter);
                    case 6
                        birgeVieta(1,func,interval,tolerance,maxIter,'file');
                    case 7
                        generalMethod(1,func,interval,tolerance,maxIter,'file');
                end 
            catch 
            end 
                
            
        end

        % Button pushed function: SOLVEButton
        function SOLVEButtonPushed(app, event)
            app.Label.Text = '';
            error_msg = validate_eq(app, app.EditField.Value);
            if (error_msg ~= "")
                app.Label.Text = error_msg;
            else
                app.PleasechoosemodeLabel.Enable = 'on';
                app.FastModeButton.Enable = 'on';
                app.SingleStepModeButton.Enable = 'on';
            end        
        end

        % Button pushed function: SingleStepModeButton
        function SingleStepModeButtonPushed(app, event)
          msg = validate_eq(app, app.EditField.Value);
            if (msg ~= "")
                app.Label.Text = msg;
            else 
            algorithm_choosen = app.AlgorithmsDropDown.Value;
                switch algorithm_choosen
                    case 'Bisection'
                       bi = Bisection;
                       bi.solve(app.EditField.Value, 'single');
                    case 'False-position'
                       fp = False_Position;
                       fp.solve(app.EditField.Value, 'single');
                    case 'Fixed point'
                       fp = Fixed_Point;
                       fp.solve(app.EditField.Value, 'single');
                    case 'Newton-Raphson'
                       fp = app1;
                       fp.solve(app.EditField.Value, 'single');
                    case 'Secant'
                       
                    case 'Bierge Vieta'
                         birgeVieta(0,app.EditField.Value,0,0,0,'single');
                    case 'Interpolation'
                        
                    case 'General Algorithm'
                         generalMethod(0,app.EditField.Value,0,0,0,'single');
                         
                    otherwise
                       error('Algorithm not supported'); 
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RootSolverUIFigure = uifigure;
            app.RootSolverUIFigure.Name = 'Root Solver';
            app.SOLVEButton = uibutton(app.RootSolverUIFigure, 'push');
            app.SOLVEButton.ButtonPushedFcn = createCallbackFcn(app, @SOLVEButtonPushed, true);
            app.SOLVEButton.Text = 'SOLVE';
            app.AlgorithmsDropDownLabel = uilabel(app.RootSolverUIFigure);
            app.AlgorithmsDropDownLabel.Text = 'Algorithms';
            app.AlgorithmsDropDown = uidropdown(app.RootSolverUIFigure);
            app.AlgorithmsDropDown.Items = {'Bisection', 'False-position', 'Fixed point', 'Newton-Raphson', 'Secant', 'Bierge Vieta', 'General Algorithm'};
            app.AlgorithmsDropDown.Value = 'Bisection';
            app.EditField = uieditfield(app.RootSolverUIFigure, 'text');
            app.EditField.Value = 'Enter equation to be solved';
            app.Label = uilabel(app.RootSolverUIFigure);
            app.Label.Text = '';
            app.SingleStepModeButton = uibutton(app.RootSolverUIFigure, 'push');
            app.SingleStepModeButton.ButtonPushedFcn = createCallbackFcn(app, @SingleStepModeButtonPushed, true);
            app.SingleStepModeButton.Enable = 'off';
            app.SingleStepModeButton.Text = 'Single Step Mode';
            app.FastModeButton = uibutton(app.RootSolverUIFigure, 'push');
            app.FastModeButton.ButtonPushedFcn = createCallbackFcn(app, @FastModeButtonPushed, true);
            app.FastModeButton.Enable = 'off';
            app.FastModeButton.Text = 'Fast Mode';
            app.PleasechoosemodeLabel = uilabel(app.RootSolverUIFigure);
            app.PleasechoosemodeLabel.Enable = 'off';
            app.PleasechoosemodeLabel.Text = 'Please choose mode';
            app.ReadFromFileButton = uibutton(app.RootSolverUIFigure, 'push');
            app.ReadFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFromFileBtnPushed, true);
            app.ReadFromFileButton.Text = {'ReadFromFile'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = GeneralGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RootSolverUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RootSolverUIFigure)
        end
    end
end